classdef ElectroApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Panel_3                       matlab.ui.container.Panel
        ElectroAppTringulodeTresCargasLabel  matlab.ui.control.Label
        Panel_2                       matlab.ui.container.Panel
        unidades                      matlab.ui.control.DropDown
        UnidadQ3DropDown              matlab.ui.control.DropDown
        UnidadQ2DropDown              matlab.ui.control.DropDown
        UnidadQ1DropDown              matlab.ui.control.DropDown
        y5Valor                       matlab.ui.control.NumericEditField
        q1CLabel_6                    matlab.ui.control.Label
        y4Valor                       matlab.ui.control.NumericEditField
        q1CLabel_5                    matlab.ui.control.Label
        y3Valor                       matlab.ui.control.NumericEditField
        q1CLabel_4                    matlab.ui.control.Label
        y2Valor                       matlab.ui.control.NumericEditField
        q1CLabel_3                    matlab.ui.control.Label
        y1Valor                       matlab.ui.control.NumericEditField
        q1CLabel_2                    matlab.ui.control.Label
        Trescargaspuntualesseubicanenlosvrticesdeuntringuloequilteroq1q2positivasyq3negativaLaaplicacincalculaelcampoelctricoalolargodelaalturayeltrabajonecesarioparaensamblarelsistemaapartirdelosvaloresdelascargasyladistanciaentreellasLabel  matlab.ui.control.Label
        aField                        matlab.ui.control.NumericEditField
        amLabel                       matlab.ui.control.Label
        CalculartrabajoButton         matlab.ui.control.Button
        CalcularcampoelectricoButton  matlab.ui.control.Button
        q3Field                       matlab.ui.control.NumericEditField
        q3CLabel                      matlab.ui.control.Label
        q2Field                       matlab.ui.control.NumericEditField
        q2CLabel                      matlab.ui.control.Label
        q1Field                       matlab.ui.control.NumericEditField
        q1CLabel                      matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        Panel                         matlab.ui.container.Panel
        ResultadoCampo                matlab.ui.control.TextArea
        ResultadosdelcampoelctricoLabel  matlab.ui.control.Label
        ResultadoLabel                matlab.ui.control.Label
        graficaCampo                  matlab.ui.control.UIAxes
        graficaTrabajo                matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularcampoelectricoButton
        function CalcularcampoelectricoButtonPushed(app, event)
        % ==============================
        % Factores de conversión unidades
        % ==============================
        % Se define un factor de conversión dependiendo de la unidad seleccionada
        % en la interfaz para las cargas (q1, q2 y q3). 
        % El valor inicial es 1 (supone Coulomb), y si el usuario selecciona microCoulomb (µC)
        % o nanoCoulomb (nC), se multiplica por el factor correspondiente.
        
        factor1 = 1; 
        switch app.UnidadQ1DropDown.Value
            case 'µC'   % microCoulomb
                factor1 = 1e-6;
            case 'nC'   % nanoCoulomb
                factor1 = 1e-9;
        end
        
        factor2 = 1; 
        switch app.UnidadQ2DropDown.Value
            case 'µC'
                factor2 = 1e-6;
            case 'nC'
                factor2 = 1e-9;
        end
        
        factor3 = 1; 
        switch app.UnidadQ3DropDown.Value
            case 'µC'
                factor3 = 1e-6;
            case 'nC'
                factor3 = 1e-9;
        end
        
        
        % ====================================
        % Obtenemos los valores desde la interfaz
        % ====================================
        % Se leen los valores numéricos que el usuario escribió en los campos de la App.
        % Cada carga se multiplica por su respectivo factor de conversión para quedar en Coulomb.
        % También se obtiene el valor del lado 'a' del triángulo y se calcula la altura 'h'.
        % Finalmente, se define la constante de Coulomb ke.
        
        q1 = double(app.q1Field.Value) * factor1;  % carga 1 [C]
        q2 = double(app.q2Field.Value) * factor2;  % carga 2 [C]
        q3 = double(app.q3Field.Value) * factor3;  % carga 3 [C]
        a  = double(app.aField.Value);             % lado del triángulo [m]
        h  = a * sqrt(3) / 2;                      % altura del triángulo equilátero [m]
        ke = 9e9;                                  % constante de Coulomb [N·m²/C²]
        
        
        % ====================================
        % Lista de coordenadas Y
        % ====================================
        % Se obtiene desde la interfaz un conjunto de posiciones (en eje Y) 
        % donde se calculará el campo eléctrico.
        
        % Inicialización de las coordenadas Y
        coordY = zeros(5, 1); % Vector para almacenar 5 coordenadas Y
        
        % Obtener valores de las coordenadas Y desde la interfaz de usuario
        coordY(1) = double(app.y1Valor.Value);
        coordY(2) = double(app.y2Valor.Value);
        coordY(3) = double(app.y3Valor.Value);
        coordY(4) = double(app.y4Valor.Value);
        coordY(5) = double(app.y5Valor.Value);
        
                coordY = [double(app.y1Valor.Value), double(app.y2Valor.Value), ...
                  double(app.y3Valor.Value), double(app.y4Valor.Value), ...
                  double(app.y5Valor.Value)];
    
        % Definir el factor de conversión según la unidad seleccionada
        switch app.unidades.Value
            case 'm'
                valor = 1; % Metros
            case 'cm'
                valor = 100; % Centímetros a metros
            case 'mm'
                valor = 1000; % Milímetros a metros
            otherwise
                error('Unidad no válida: %s', app.unidades.Value); % Manejo de errores
        end
        
        % Modificar las distancias para que todas estén en las unidades deseadas
        coordY = coordY / valor; % Convertir todas las coordenadas a la misma unidad
        
            a = a / valor; % Convertir 'a' a la misma unidad


        % ====================================
        % Llamar a la función externa
        % ====================================
        % Se llama a una función definida aparte (campoElectrico) que calcula
        % la magnitud del campo eléctrico en cada una de las posiciones Y.
        % Devuelve un vector con las magnitudes correspondientes.
        
        Emagnitud = campoElectrico(a, h, q1, q2, q3, ke, coordY);
        
        
        % ====================================
        % Crear cabecera de resultados
        % ====================================
        % Se arma un arreglo de strings con los encabezados de la tabla que se mostrará.
        % La tabla tendrá el número de la posición, el valor de Y y la magnitud de E.
        
        lineas = [" Nº   |   y [m]   |   |E| [N/C]"; ...
                  "-------------------------------"];
        
        
        % ====================================
        % Agrega los resultados
        % ====================================
        % Se recorre cada posición en 'coordY' y se formatea una fila con:
        % índice, valor de Y (en metros) y magnitud del campo (en notación científica).
        % Cada fila se añade al arreglo 'lineas'.
        
        for i = 1:length(coordY)
            fila = sprintf(" %d   |  %.3f   |  %.3e", i, coordY(i), Emagnitud(i));
            lineas(end+1) = fila; %#ok<AGROW> % <== se permite crecimiento dinámico del arreglo
        end
        
        
        % ====================================
        % Mostrar resultados en el TextArea
        % ====================================
        % Finalmente, se muestra la tabla de resultados dentro del área de texto
        % de la aplicación (app.ResultadoCampo).
        
        app.ResultadoCampo.Value = lineas;
        
        
        % ====================================
        % Gráfica de resultados
        % ====================================
        % Se dibuja la gráfica del campo eléctrico vs. posición Y en el componente
        % de la app 'graficaCampo'. 
        % - Líneas rojas con marcadores circulares amarillos
        % - Grosor de línea de 1.8
        % - Se activa la cuadrícula.

plot(app.graficaCampo, coordY, Emagnitud, 'r-o', ...
     'LineWidth', 1.8, 'MarkerSize', 6, 'MarkerFaceColor','y');

grid(app.graficaCampo, 'on');


        end

        % Button pushed function: CalculartrabajoButton
        function CalculartrabajoButtonPushed(app, event)
             % Factores de conversión unidades
                factor1 = 1; 
                switch app.UnidadQ1DropDown.Value
                    case 'µC'
                        factor1 = 1e-6;
                    case 'nC'
                        factor1 = 1e-9;
                end
            
                factor2 = 1; 
                switch app.UnidadQ2DropDown.Value
                    case 'µC'
                        factor2 = 1e-6;
                    case 'nC'
                        factor2 = 1e-9;
                end
            
                factor3 = 1; 
                switch app.UnidadQ3DropDown.Value
                    case 'µC'
                        factor3 = 1e-6;
                    case 'nC'
                        factor3 = 1e-9;
                end
           
                % Obtener valores desde la interfaz 
                q1 = app.q1Field.Value * factor1;
                q2 = app.q2Field.Value * factor2;
                q3 = app.q3Field.Value * factor3;
                a  = app.aField.Value; % metros
            
                % Llamar al script del trabajo
                W = trabajoCargas(q1, q2, q3, a);
            
                % Mostrar resultado 
                app.ResultadoLabel.Text = ['Trabajo: ' num2str(W) ' J'];
            
                % gráfica
                k = 9e9;
                a_values = linspace(0.05, 1, 100); % de 5 cm a 1 m
                W_values = k * (q1*q2 + q1*q3 + q2*q3) ./ a_values;
            
                % Dibujar en el UIAxes de trabajo
                plot(app.graficaTrabajo, a_values, W_values, 'r-', 'LineWidth', 2);
                xlabel(app.graficaTrabajo, 'Distancia a (m)');
                ylabel(app.graficaTrabajo, 'Trabajo W (J)');
                title(app.graficaTrabajo, 'Trabajo en función de la distancia a');
                grid(app.graficaTrabajo, 'on');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.graficaTrabajo = uiaxes(app.Panel);
            title(app.graficaTrabajo, 'Trabajo en función de la distancia a')
            xlabel(app.graficaTrabajo, 'Distancia a (m)')
            ylabel(app.graficaTrabajo, 'Trabajo W (J)')
            zlabel(app.graficaTrabajo, 'Z')
            app.graficaTrabajo.SubtitleFontWeight = 'bold';
            app.graficaCampo = uiaxes(app.Panel);
            title(app.graficaCampo, 'Magnitud del campo eléctrico en el punto P')
            xlabel(app.graficaCampo, 'Coordenada Y [m]')
            ylabel(app.graficaCampo, 'Campo |E| [N/C]')
            zlabel(app.graficaCampo, 'Z')
            app.ResultadoLabel = uilabel(app.Panel);
            app.ResultadoLabel.Text = 'Trabajo (W): 0,00 J';
            app.ResultadosdelcampoelctricoLabel = uilabel(app.Panel);
            app.ResultadosdelcampoelctricoLabel.Text = 'Campo de los datos ingresados';
            app.ResultadoCampo = uitextarea(app.Panel);
            app.Panel_2 = uipanel(app.UIFigure);
            app.Image = uiimage(app.Panel_2);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'imagen.png');
            app.q1CLabel = uilabel(app.Panel_2);
            app.q1CLabel.Text = 'q1 :';
            app.q1Field = uieditfield(app.Panel_2, 'numeric');
            app.q2CLabel = uilabel(app.Panel_2);
            app.q2CLabel.Text = 'q2  :';
            app.q2Field = uieditfield(app.Panel_2, 'numeric');
            app.q3CLabel = uilabel(app.Panel_2);
            app.q3CLabel.Text = 'q3 : ';
            app.q3Field = uieditfield(app.Panel_2, 'numeric');
            app.CalcularcampoelectricoButton = uibutton(app.Panel_2, 'push');
            app.CalcularcampoelectricoButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularcampoelectricoButtonPushed, true);
            app.CalcularcampoelectricoButton.Text = 'Calcular campo electrico';
            app.CalculartrabajoButton = uibutton(app.Panel_2, 'push');
            app.CalculartrabajoButton.ButtonPushedFcn = createCallbackFcn(app, @CalculartrabajoButtonPushed, true);
            app.CalculartrabajoButton.Text = 'Calcular trabajo';
            app.amLabel = uilabel(app.Panel_2);
            app.amLabel.Text = 'a : ';
            app.aField = uieditfield(app.Panel_2, 'numeric');
            app.Trescargaspuntualesseubicanenlosvrticesdeuntringuloequilteroq1q2positivasyq3negativaLaaplicacincalculaelcampoelctricoalolargodelaalturayeltrabajonecesarioparaensamblarelsistemaapartirdelosvaloresdelascargasyladistanciaentreellasLabel = uilabel(app.Panel_2);
            app.Trescargaspuntualesseubicanenlosvrticesdeuntringuloequilteroq1q2positivasyq3negativaLaaplicacincalculaelcampoelctricoalolargodelaalturayeltrabajonecesarioparaensamblarelsistemaapartirdelosvaloresdelascargasyladistanciaentreellasLabel.Text = '"Tres cargas puntuales se ubican en los vértices de un triángulo equilátero ( q1,q2: positivas y q3 negativa). La aplicación calcula el campo eléctrico a lo largo de la altura y el trabajo necesario para ensamblar el sistema, a partir de los valores de las cargas y la distancia entre ellas."';
            app.q1CLabel_2 = uilabel(app.Panel_2);
            app.q1CLabel_2.Text = 'y1';
            app.y1Valor = uieditfield(app.Panel_2, 'numeric');
            app.q1CLabel_3 = uilabel(app.Panel_2);
            app.q1CLabel_3.Text = 'y2';
            app.y2Valor = uieditfield(app.Panel_2, 'numeric');
            app.q1CLabel_4 = uilabel(app.Panel_2);
            app.q1CLabel_4.Text = 'y3';
            app.y3Valor = uieditfield(app.Panel_2, 'numeric');
            app.q1CLabel_5 = uilabel(app.Panel_2);
            app.q1CLabel_5.Text = 'y4';
            app.y4Valor = uieditfield(app.Panel_2, 'numeric');
            app.q1CLabel_6 = uilabel(app.Panel_2);
            app.q1CLabel_6.Text = 'y5';
            app.y5Valor = uieditfield(app.Panel_2, 'numeric');
            app.UnidadQ1DropDown = uidropdown(app.Panel_2);
            app.UnidadQ1DropDown.Items = {'C', 'µC', 'nC'};
            app.UnidadQ1DropDown.Value = 'C';
            app.UnidadQ2DropDown = uidropdown(app.Panel_2);
            app.UnidadQ2DropDown.Items = {'C', 'µC', 'nC'};
            app.UnidadQ2DropDown.Value = 'C';
            app.UnidadQ3DropDown = uidropdown(app.Panel_2);
            app.UnidadQ3DropDown.Items = {'C', 'µC', 'nC'};
            app.UnidadQ3DropDown.Value = 'C';
            app.unidades = uidropdown(app.Panel_2);
            app.unidades.Items = {'m', 'cm', 'mm'};
            app.unidades.Value = 'm';
            app.Panel_3 = uipanel(app.UIFigure);
            app.ElectroAppTringulodeTresCargasLabel = uilabel(app.Panel_3);
            app.ElectroAppTringulodeTresCargasLabel.Text = 'ElectroApp: Triángulo de Tres Cargas';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ElectroApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end